classdef forced_oscillator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForcedoscillatorUIFigure  matlab.ui.Figure
        GainAxes                  matlab.ui.control.UIAxes
        ASpinner                  matlab.ui.control.Spinner
        BSpinner                  matlab.ui.control.Spinner
        CSpinner                  matlab.ui.control.Spinner
        RootsAxes                 matlab.ui.control.UIAxes
        ypp                       matlab.ui.control.Label
        yp                        matlab.ui.control.Label
        y                         matlab.ui.control.Label
        LagAxes                   matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            compute_all(app,[]);
        end

        % Value changed function: ASpinner, BSpinner, CSpinner
        function compute_all(app, event)
            A = app.ASpinner.Value;
            B = app.BSpinner.Value;
            C = app.CSpinner.Value;
            charpol = [A B C];
            s = roots(charpol);
            plot(app.RootsAxes,real(s),imag(s),'rx','markersize',10)
            
            omega = linspace(0,5,5001);
            H = 1./polyval(charpol,1i*omega);
            plot(app.GainAxes,omega,abs(H),'linew',2)
            [Gmax,k] = max(abs(H));
            set(app.GainAxes,'xtick',omega([1 k end]))
            
            plot(app.LagAxes,omega,angle(conj(H))/pi,'linew',2)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForcedoscillatorUIFigure = uifigure;
            app.ForcedoscillatorUIFigure.Name = 'Forced oscillator';
            app.GainAxes = uiaxes(app.ForcedoscillatorUIFigure);
            title(app.GainAxes, 'Gain')
            xlabel(app.GainAxes, '')
            ylabel(app.GainAxes, 'G')
            app.GainAxes.YLim = [0 20];
            app.ASpinner = uispinner(app.ForcedoscillatorUIFigure);
            app.ASpinner.Step = 0.25;
            app.ASpinner.Limits = [0.5 5];
            app.ASpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.ASpinner.Value = 1;
            app.BSpinner = uispinner(app.ForcedoscillatorUIFigure);
            app.BSpinner.Step = 0.02;
            app.BSpinner.Limits = [0 6];
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.CSpinner = uispinner(app.ForcedoscillatorUIFigure);
            app.CSpinner.Step = 0.5;
            app.CSpinner.Limits = [0 8];
            app.CSpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.CSpinner.Value = 4;
            app.RootsAxes = uiaxes(app.ForcedoscillatorUIFigure);
            title(app.RootsAxes, 'Characteristic roots')
            app.RootsAxes.XLim = [-6 2];
            app.RootsAxes.YLim = [-4 4];
            app.RootsAxes.XTick = 0;
            app.RootsAxes.XTickLabel = '';
            app.RootsAxes.YTick = 0;
            app.RootsAxes.YTickLabel = '';
            app.ypp = uilabel(app.ForcedoscillatorUIFigure);
            app.ypp.Text = 'y''''  + ';
            app.yp = uilabel(app.ForcedoscillatorUIFigure);
            app.yp.Text = 'y''  + ';
            app.y = uilabel(app.ForcedoscillatorUIFigure);
            app.y.Text = 'y = exp(i*om*t) ';
            app.LagAxes = uiaxes(app.ForcedoscillatorUIFigure);
            title(app.LagAxes, 'Lag')
            xlabel(app.LagAxes, 'frequency')
            ylabel(app.LagAxes, '\alpha / \pi')
            app.LagAxes.YLim = [0 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = forced_oscillator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForcedoscillatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForcedoscillatorUIFigure)
        end
    end
end