classdef unforced_oscillator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnforcedoscillatorUIFigure  matlab.ui.Figure
        SolAxes                     matlab.ui.control.UIAxes
        ASpinnerLabel               matlab.ui.control.Label
        ASpinner                    matlab.ui.control.Spinner
        BSpinnerLabel               matlab.ui.control.Label
        BSpinner                    matlab.ui.control.Spinner
        CSpinnerLabel               matlab.ui.control.Label
        CSpinner                    matlab.ui.control.Spinner
        RootsAxes                   matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            compute_all(app,[]);
        end

        % Value changed function: ASpinner, BSpinner, CSpinner
        function compute_all(app, event)
            A = app.ASpinner.Value;
            B = app.BSpinner.Value;
            C = app.CSpinner.Value;
            s = roots([A B C]);
            plot(app.RootsAxes,real(s),imag(s),'rx','markersize',10)
            
            function dydt = oscillator(t,y)
                dydt = [ y(2);
                    (-B*y(2) - C*y(1))/A ];
            end
            t = linspace(0,20,1000);
            [t,y] = ode45(@oscillator,t,[1;0]);
            plot(app.SolAxes,t,y(:,1),'linew',2)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnforcedoscillatorUIFigure = uifigure;
            app.UnforcedoscillatorUIFigure.Name = 'Unforced oscillator';
            app.SolAxes = uiaxes(app.UnforcedoscillatorUIFigure);
            title(app.SolAxes, 'Ay'''' + By'' + Cy = 0')
            xlabel(app.SolAxes, 't')
            ylabel(app.SolAxes, 'y(t)')
            app.SolAxes.XLim = [0 20];
            app.SolAxes.YLim = [-1 1];
            app.ASpinnerLabel = uilabel(app.UnforcedoscillatorUIFigure);
            app.ASpinnerLabel.Text = 'A';
            app.ASpinner = uispinner(app.UnforcedoscillatorUIFigure);
            app.ASpinner.Step = 0.25;
            app.ASpinner.Limits = [0.25 5];
            app.ASpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.ASpinner.Value = 1;
            app.BSpinnerLabel = uilabel(app.UnforcedoscillatorUIFigure);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.UnforcedoscillatorUIFigure);
            app.BSpinner.Step = 0.2;
            app.BSpinner.Limits = [0 6];
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.CSpinnerLabel = uilabel(app.UnforcedoscillatorUIFigure);
            app.CSpinnerLabel.Text = 'C';
            app.CSpinner = uispinner(app.UnforcedoscillatorUIFigure);
            app.CSpinner.Step = 0.5;
            app.CSpinner.Limits = [0 10];
            app.CSpinner.ValueChangedFcn = createCallbackFcn(app, @compute_all, true);
            app.CSpinner.Value = 4;
            app.RootsAxes = uiaxes(app.UnforcedoscillatorUIFigure);
            title(app.RootsAxes, 'Characteristic roots')
            app.RootsAxes.XLim = [-7 1];
            app.RootsAxes.YLim = [-4 4];
            app.RootsAxes.XTick = 0;
            app.RootsAxes.XTickLabel = '';
            app.RootsAxes.YTick = 0;
            app.RootsAxes.YTickLabel = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = unforced_oscillator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnforcedoscillatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnforcedoscillatorUIFigure)
        end
    end
end